classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GHEVARUIFigure                matlab.ui.Figure
        DIAGNOSISEXPLAINABILITYPanel  matlab.ui.container.Panel
        RETINALANALYSISPanel          matlab.ui.container.Panel
        EXUDATESEGMENTATIONLabel      matlab.ui.control.Label
        OPTICDISCLOCALIZATIONLabel    matlab.ui.control.Label
        MICROANEURYSMDETECTIONLabel   matlab.ui.control.Label
        VESSELSEGMENTATIONLabel       matlab.ui.control.Label
        ExudateImage                  matlab.ui.control.Image
        MicroaneurysmImage            matlab.ui.control.Image
        OpticDiscImage                matlab.ui.control.Image
        VesselImage                   matlab.ui.control.Image
        AnalyzeRetinaButton           matlab.ui.control.Button
        ENHANCEDFUNDUSIMAGELabel      matlab.ui.control.Label
        EnhancedImage                 matlab.ui.control.Image
        IMAGEINPUTQUALITYPanel        matlab.ui.container.Panel
        EnhancedImageButton           matlab.ui.control.Button
        QualityScoreLabel             matlab.ui.control.Label
        FOVLabel                      matlab.ui.control.Label
        IlluminationLabel             matlab.ui.control.Label
        FocusLabel                    matlab.ui.control.Label
        IMAGEQUALITYASSESSMENTLabel   matlab.ui.control.Label
        ORIGINALFUNDUSIMAGELabel      matlab.ui.control.Label
        OriginalImage                 matlab.ui.control.Image
        LoadFundusImageButton         matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFundusImageButton
        function LoadFundusImageButtonPushed(app, event)
            % Select fundus image
    [file, path] = uigetfile( ...
        {'*.jpg;*.jpeg;*.png;*.bmp;*.tif', ...
        'Fundus Images'}, ...
        'Select a Fundus Image');

    if isequal(file, 0)
        return;
    end

    imagePath = fullfile(path, file);

    % Read image
    img = imread(imagePath);

    % Display original image
    app.OriginalImage.ImageSource = imagePath;


    %% IMAGE QUALITY ASSESSMENT

    % Convert to grayscale
    if size(img,3) == 3
        grayImg = rgb2gray(img);
    else
        grayImg = img;
    end

    grayImg = im2double(grayImg);


    % ---------- 1. FOCUS ----------
    % Laplacian-based sharpness measurement
    lap = del2(grayImg);
    focusScore = var(lap(:));

    if focusScore > 0.00015
        focusStatus = "GOOD";
    elseif focusScore > 0.00005
        focusStatus = "BORDERLINE";
    else
        focusStatus = "POOR";
    end


    % ---------- 2. ILLUMINATION ----------
    meanBrightness = mean(grayImg(:));

    if meanBrightness >= 0.15 && meanBrightness <= 0.80
        illuminationStatus = "GOOD";
    elseif meanBrightness >= 0.08 && meanBrightness <= 0.90
        illuminationStatus = "BORDERLINE";
    else
        illuminationStatus = "POOR";
    end


    % ---------- 3. FIELD OF VIEW ----------
    % Detect pixels belonging to the visible retinal region
    retinalMask = grayImg > 0.03;

    fovRatio = nnz(retinalMask) / numel(retinalMask);

    if fovRatio > 0.35
        fovStatus = "GOOD";
    elseif fovRatio > 0.20
        fovStatus = "BORDERLINE";
    else
        fovStatus = "POOR";
    end


    % ---------- DISPLAY RESULTS ----------

    app.FocusLabel.Text = "FOCUS:          " + focusStatus;

    app.IlluminationLabel.Text = ...
        "ILLUMINATION:   " + illuminationStatus;

    app.FOVLabel.Text = ...
        "FIELD OF VIEW:  " + fovStatus;


    % ---------- OVERALL QUALITY ----------

    goodCount = sum( ...
        [focusStatus == "GOOD", ...
         illuminationStatus == "GOOD", ...
         fovStatus == "GOOD"]);

    borderlineCount = sum( ...
        [focusStatus == "BORDERLINE", ...
         illuminationStatus == "BORDERLINE", ...
         fovStatus == "BORDERLINE"]);


    if goodCount == 3
        overallStatus = "GOOD";

    elseif goodCount + borderlineCount >= 2
        overallStatus = "BORDERLINE";

    else
        overallStatus = "UNGRADEABLE";
    end


    app.QualityScoreLabel.Text = ...
        "OVERALL QUALITY: " + overallStatus;


    % Display filename in Command Window
    fprintf("Loaded fundus image: %s\n", file);

        end

        % Button pushed function: EnhancedImageButton
        function EnhancedImageButtonPushed(app, event)
         
                % Make sure an image has been loaded
                if isempty(app.OriginalImage.ImageSource)
                    uialert(app.GHEVARUIFigure, ...
                        "Please load a fundus image first.", ...
                        "No Image");
                    return;
                end

                % Get the currently loaded image
                imagePath = app.OriginalImage.ImageSource;
                img = imread(imagePath);

                % Convert to RGB if necessary
                if size(img,3) == 1
                    img = cat(3,img,img,img);
                end

                % Convert RGB -> LAB
                labImg = rgb2lab(img);

                % Extract L channel
                L = labImg(:,:,1);

                % Normalize L to [0,1]
                L = mat2gray(L);

                % Apply CLAHE
                L_enhanced = adapthisteq(L, ...
                    'NumTiles', [8 8], ...
                    'ClipLimit', 0.01);

                % Put enhanced L channel back
                labImg(:,:,1) = L_enhanced * 100;

                % Convert LAB -> RGB
                enhancedImg = lab2rgb(labImg);

                % Make sure values are valid
                enhancedImg = im2uint8(enhancedImg);

                % Display enhanced image
                app.EnhancedImage.ImageSource = enhancedImg;

        end

        % Button pushed function: AnalyzeRetinaButton
        function AnalyzeRetinaButtonPushed(app, event)

    % Check whether an image has been loaded
    if isempty(app.OriginalImage.ImageSource)
        uialert(app.GHEVARUIFigure, ...
            "Please load a fundus image first.", ...
            "No Image");
        return;
    end

    %% GET IMAGE

    if isempty(app.EnhancedImage.ImageSource)

        img = imread(app.OriginalImage.ImageSource);

    else

        if ischar(app.EnhancedImage.ImageSource) || ...
                isstring(app.EnhancedImage.ImageSource)

            img = imread(app.EnhancedImage.ImageSource);

        else
            img = app.EnhancedImage.ImageSource;
        end

    end

    % Ensure RGB
    if size(img,3) == 1
        img = cat(3,img,img,img);
    end

    %% =========================================================
    %                    VESSEL SEGMENTATION
    % ==========================================================

    greenChannel = im2double(img(:,:,2));

    greenChannel = adapthisteq(greenChannel, ...
        'NumTiles',[8 8], ...
        'ClipLimit',0.01);

    % Dark structures = possible vessels
    vesselMask = imbinarize( ...
        imcomplement(greenChannel), ...
        'adaptive', ...
        'Sensitivity',0.55);

    % Remove tiny noise
    vesselMask = bwareaopen(vesselMask,30);

    % Clean vessel mask
    vesselMask = imopen(vesselMask,strel('disk',1));
    vesselMask = imclose(vesselMask,strel('disk',1));

    % Display binary vessel map
    vesselRGB = zeros(size(img),'uint8');

    vesselRGB(:,:,1) = uint8(vesselMask)*255;
    vesselRGB(:,:,2) = uint8(vesselMask)*255;
    vesselRGB(:,:,3) = uint8(vesselMask)*255;

    app.VesselImage.ImageSource = vesselRGB;


    %% =========================================================
    %                     OPTIC DISC
    % ==========================================================

    grayImg = rgb2gray(img);

    brightMask = grayImg > 0.80;

    brightMask = bwareaopen(brightMask,100);

    stats = regionprops(brightMask, ...
        'Area','Centroid');

    opticDiscMask = false(size(grayImg));

    if ~isempty(stats)

        [~,idx] = max([stats.Area]);

        center = stats(idx).Centroid;

        radius = 35;

        [X,Y] = meshgrid(1:size(grayImg,2), ...
                         1:size(grayImg,1));

        opticDiscMask = ...
            (X-center(1)).^2 + ...
            (Y-center(2)).^2 <= radius^2;

    end

    % Highlight optic disc
    opticDiscImage = img;

    boundary = bwperim(opticDiscMask);

    opticDiscImage(:,:,1) = uint8( ...
        min(double(opticDiscImage(:,:,1)) + 150*double(boundary),255));

    opticDiscImage(:,:,2) = uint8( ...
        double(opticDiscImage(:,:,2)).*(1-double(boundary)));

    opticDiscImage(:,:,3) = uint8( ...
        double(opticDiscImage(:,:,3)).*(1-double(boundary)));

    app.OpticDiscImage.ImageSource = opticDiscImage;


    %% =========================================================
    %                    MICROANEURYSM DETECTION
    % ==========================================================

    % Microaneurysms are small dark/red candidate lesions.
    redChannel = im2double(img(:,:,1));
    greenChannel = im2double(img(:,:,2));

    % Red-vs-green contrast
    lesionContrast = redChannel - greenChannel;

    % Normalize
    lesionContrast = mat2gray(lesionContrast);

    % Enhance small dark structures
    darkResponse = imbothat(lesionContrast, ...
        strel('disk',4));

    % Threshold candidate lesions
    microMask = darkResponse > graythresh(darkResponse);

    % Remove very small and very large regions
    microMask = bwareaopen(microMask,5);

    microMask = bwareafilt(microMask,[5 150]);

    % Remove optic disc area from candidate lesion mask
    microMask(opticDiscMask) = false;

    % Create highlighted image
    microImage = img;

    microBoundary = bwperim(microMask);

    % Highlight candidate microaneurysms
    microImage(:,:,1) = uint8( ...
        min(double(microImage(:,:,1)) + ...
        180*double(microBoundary),255));

    microImage(:,:,2) = uint8( ...
        double(microImage(:,:,2)).*(1-double(microBoundary)));

    microImage(:,:,3) = uint8( ...
        double(microImage(:,:,3)).*(1-double(microBoundary)));

    app.MicroaneurysmImage.ImageSource = microImage;


    %% =========================================================
    %                       EXUDATE DETECTION
    % ==========================================================

    % Exudates are bright retinal lesions.
    grayImg = im2double(rgb2gray(img));

    % Enhance bright structures
    brightResponse = imtophat(grayImg, ...
        strel('disk',10));

    % Threshold
    exudateMask = brightResponse > ...
        graythresh(brightResponse);

    % Remove tiny noise
    exudateMask = bwareaopen(exudateMask,20);

    % Remove optic disc
    exudateMask(opticDiscMask) = false;

    % Keep reasonably sized regions
    exudateMask = bwareafilt(exudateMask,[20 3000]);

    % Highlight exudates
    exudateImage = img;

    exudateBoundary = bwperim(exudateMask);

    % Highlight candidate exudates
    exudateImage(:,:,1) = uint8( ...
        min(double(exudateImage(:,:,1)) + ...
        180*double(exudateBoundary),255));

    exudateImage(:,:,2) = uint8( ...
        min(double(exudateImage(:,:,2)) + ...
        180*double(exudateBoundary),255));

    exudateImage(:,:,3) = uint8( ...
        double(exudateImage(:,:,3)).*(1-double(exudateBoundary)));

    app.ExudateImage.ImageSource = exudateImage;


    %% =========================================================
    %                       STATUS
    % ==========================================================

    % Update the button after successful analysis
    app.AnalyzeRetinaButton.Text = "Analysis Complete";
     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GHEVARUIFigure = uifigure('Visible', 'off');
            app.GHEVARUIFigure.Name = 'GHEVAR';
            app.Panel = uipanel(app.GHEVARUIFigure);
            app.Panel.Title = 'GHEVAR  |  AI-Assisted Diabetic Retinopathy Screening';
            app.Panel.Tag = 'headerpanel';
            app.IMAGEINPUTQUALITYPanel = uipanel(app.GHEVARUIFigure);
            app.IMAGEINPUTQUALITYPanel.Title = 'IMAGE INPUT & QUALITY';
            app.IMAGEINPUTQUALITYPanel.Tag = 'ImageInputPanel';
            app.LoadFundusImageButton = uibutton(app.IMAGEINPUTQUALITYPanel, 'push');
            app.LoadFundusImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFundusImageButtonPushed, true);
            app.LoadFundusImageButton.Tag = 'LoadImageButton';
            app.LoadFundusImageButton.Text = 'Load Fundus Image';
            app.OriginalImage = uiimage(app.IMAGEINPUTQUALITYPanel);
            app.OriginalImage.Tag = 'OriginalImage';
            app.ORIGINALFUNDUSIMAGELabel = uilabel(app.IMAGEINPUTQUALITYPanel);
            app.ORIGINALFUNDUSIMAGELabel.Tag = 'OriginalImageLabel';
            app.ORIGINALFUNDUSIMAGELabel.Text = 'ORIGINAL FUNDUS IMAGE';
            app.IMAGEQUALITYASSESSMENTLabel = uilabel(app.IMAGEINPUTQUALITYPanel);
            app.IMAGEQUALITYASSESSMENTLabel.Tag = 'QualityScoreLabel';
            app.IMAGEQUALITYASSESSMENTLabel.Text = 'IMAGE QUALITY ASSESSMENT';
            app.FocusLabel = uilabel(app.IMAGEINPUTQUALITYPanel);
            app.FocusLabel.Tag = 'FocusLabel';
            app.FocusLabel.Text = 'FOCUS:    ';
            app.IlluminationLabel = uilabel(app.IMAGEINPUTQUALITYPanel);
            app.IlluminationLabel.Tag = 'IlluminationLabel';
            app.IlluminationLabel.Text = 'ILLUMINATION: ';
            app.FOVLabel = uilabel(app.IMAGEINPUTQUALITYPanel);
            app.FOVLabel.Tag = 'FOVLabel';
            app.FOVLabel.Text = 'FIELD OF VIEW: ';
            app.QualityScoreLabel = uilabel(app.IMAGEINPUTQUALITYPanel);
            app.QualityScoreLabel.Tag = 'QualityScoreLabel';
            app.QualityScoreLabel.Text = 'OVERALL QUALITY:';
            app.EnhancedImageButton = uibutton(app.IMAGEINPUTQUALITYPanel, 'push');
            app.EnhancedImageButton.ButtonPushedFcn = createCallbackFcn(app, @EnhancedImageButtonPushed, true);
            app.EnhancedImageButton.Tag = 'EnhancedImageButton';
            app.EnhancedImageButton.Text = 'Enhance Image';
            app.RETINALANALYSISPanel = uipanel(app.GHEVARUIFigure);
            app.RETINALANALYSISPanel.Title = 'RETINAL ANALYSIS';
            app.RETINALANALYSISPanel.Tag = 'RetinalAnalysisPanel';
            app.EnhancedImage = uiimage(app.RETINALANALYSISPanel);
            app.EnhancedImage.Tag = 'EnhancedImage';
            app.ENHANCEDFUNDUSIMAGELabel = uilabel(app.RETINALANALYSISPanel);
            app.ENHANCEDFUNDUSIMAGELabel.Tag = 'EnhancedImageLabel';
            app.ENHANCEDFUNDUSIMAGELabel.Text = 'ENHANCED FUNDUS IMAGE';
            app.AnalyzeRetinaButton = uibutton(app.RETINALANALYSISPanel, 'push');
            app.AnalyzeRetinaButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeRetinaButtonPushed, true);
            app.AnalyzeRetinaButton.Tag = 'AnalyzeRetinaButton';
            app.AnalyzeRetinaButton.Text = 'Analyze Retina';
            app.VesselImage = uiimage(app.RETINALANALYSISPanel);
            app.VesselImage.Tag = 'VesselImage';
            app.OpticDiscImage = uiimage(app.RETINALANALYSISPanel);
            app.OpticDiscImage.Tag = 'OpticDiscImage';
            app.MicroaneurysmImage = uiimage(app.RETINALANALYSISPanel);
            app.MicroaneurysmImage.Tag = 'MicroaneurysmImage';
            app.ExudateImage = uiimage(app.RETINALANALYSISPanel);
            app.ExudateImage.Tag = 'ExudateImage';
            app.VESSELSEGMENTATIONLabel = uilabel(app.RETINALANALYSISPanel);
            app.VESSELSEGMENTATIONLabel.Text = 'VESSEL SEGMENTATION';
            app.MICROANEURYSMDETECTIONLabel = uilabel(app.RETINALANALYSISPanel);
            app.MICROANEURYSMDETECTIONLabel.Text = 'MICROANEURYSM DETECTION';
            app.OPTICDISCLOCALIZATIONLabel = uilabel(app.RETINALANALYSISPanel);
            app.OPTICDISCLOCALIZATIONLabel.Text = 'OPTIC DISC LOCALIZATION';
            app.EXUDATESEGMENTATIONLabel = uilabel(app.RETINALANALYSISPanel);
            app.EXUDATESEGMENTATIONLabel.Text = 'EXUDATE SEGMENTATION';
            app.DIAGNOSISEXPLAINABILITYPanel = uipanel(app.GHEVARUIFigure);
            app.DIAGNOSISEXPLAINABILITYPanel.Title = 'DIAGNOSIS & EXPLAINABILITY';
            app.DIAGNOSISEXPLAINABILITYPanel.Tag = 'DiagnosisPanel';
            % Show the figure after all components are created
            app.GHEVARUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GHEVARUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GHEVARUIFigure)
        end
    end
end